classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        RUNButton     matlab.ui.control.Button
        EditField_13  matlab.ui.control.EditField
        EditField     matlab.ui.control.NumericEditField
        Label         matlab.ui.control.Label
        NLabel        matlab.ui.control.Label
        TextArea_12   matlab.ui.control.TextArea
        TextArea_11   matlab.ui.control.TextArea
        TextArea_10   matlab.ui.control.TextArea
        TextArea_9    matlab.ui.control.TextArea
        TextArea_8    matlab.ui.control.TextArea
        TextArea_7    matlab.ui.control.TextArea
        TextArea_6    matlab.ui.control.TextArea
        TextArea_5    matlab.ui.control.TextArea
        TextArea_4    matlab.ui.control.TextArea
        TextArea_3    matlab.ui.control.TextArea
        TextArea_2    matlab.ui.control.TextArea
        Image         matlab.ui.control.Image
        EditField_2   matlab.ui.control.EditField
        EditField_3   matlab.ui.control.EditField
        EditField_4   matlab.ui.control.EditField
        EditField_5   matlab.ui.control.EditField
        EditField_6   matlab.ui.control.EditField
        EditField_7   matlab.ui.control.EditField
        EditField_8   matlab.ui.control.EditField
        EditField_9   matlab.ui.control.EditField
        EditField_10  matlab.ui.control.EditField
        EditField_11  matlab.ui.control.EditField
        EditField_12  matlab.ui.control.EditField
        UIAxes        matlab.ui.control.UIAxes
    end


    methods (Access = private)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowState = 'maximized';
            
            app.UIAxes.Position = [350 50 650 550];
            app.UIAxes.Title.FontSize = 18;
            app.UIAxes.Title.FontName = 'Berlin Sans FB';
            app.UIAxes.Title.Color = [0.30,0.05,0.34];
            
            app.EditField.Position = [180 700 77 22];
            app.EditField.FontSize = 16;
            
            app.RUNButton.Position = [280 670 60 30];
            app.RUNButton.FontSize = 16;
            
            app.NLabel.Position = [150 697 30 30];
            app.NLabel.FontSize = 22;
            
            app.Label.Position = [150 645 30 30];
            app.Label.FontSize = 22;
            app.Label.FontName = 'Berlin Sans FB';
            app.Label.FontColor = [0.30,0.05,0.34];
            
            app.EditField_13.Position = [180 650 77 22];
            app.EditField_13.FontSize = 16;


            app.Image.Position = [0 0 1920 1080];


        end

        % Callback function: RUNButton, UIAxes
        function RUNButtonPushed(app, event)
           
       N= app.EditField.Value; 
       theta = app.EditField_13.Value;
       app.UIAxes.Title.String = sprintf("GRAVITATIONAL INTERACTION OF %d CELESTIAL BODIES", N);
       
       pts = randn(N,3)*100;
       plotRealTime = 1;

       OT = OcTree(pts,'binCapacity',10,'style','weighted');
       %OT.shrink
       figure
       boxH = OT.plot;
       cols = lines(OT.BinCount);
       doplot3 = @(p,varargin)plot3(p(:,1),p(:,2),p(:,3),varargin{:});
       for i = 1:OT.BinCount
           set(boxH(i),'Color',cols(i,:),'LineWidth', 1+OT.BinDepths(i))
           doplot3(pts(OT.PointBins==i,:),'.','Color',cols(i,:),'markersize',22)
       end
       axis image, view(3)
       
       Bins=cell(OT.BinCount,1);
       mass = (randperm(5000,N).')*10^8;
       OT.Points(:,4) = mass; % determining mass
       [points_properties, n] = GET_properties(OT,N);  %determining color and size
       OT.Points(:,5:8) = points_properties;
       app.EditField_2.Value= sprintf("mass< %g\n",n);
       app.EditField_3.Value= sprintf("%g\n <mass<= %g\n", n, 2*n);
       app.EditField_4.Value= sprintf("%g\n <mass<= %g\n", 2*n, 3*n);
       app.EditField_5.Value= sprintf("%g\n <mass<= %g\n", 3*n, 4*n);
       app.EditField_6.Value= sprintf("%g\n <mass<= %g\n", 4*n, 5*n);
       app.EditField_7.Value= sprintf("%g\n <mass<= %g\n", 5*n, 6*n);
       app.EditField_8.Value= sprintf("%g\n <mass<= %g\n", 6*n, 7*n);
       app.EditField_9.Value= sprintf("%g\n <mass<= %g\n", 7*n, 8*n);
       app.EditField_10.Value= sprintf("%g\n <mass<= %g\n", 8*n, 9*n);
       app.EditField_11.Value= sprintf("%g\n <mass<= %g\n", 9*n, 10*n);
       app.EditField_12.Value= sprintf("mass>= %g\n", 10*n);
  for i=1:OT.BinCount
      k=1;
      for j=1:size(OT.Points,1)
          if OT.PointBins(j) == i
              Bins{i}(k,:) = OT.Points(j,:);
              k=k+1;
          end
      end
  end
  
  for k=1:length(Bins)      %to remove 0 rows
      if isempty(Bins{k})==0
       A = Bins{k};    
       A(A(:,1)==0,:) = [];
       Bins{k}=A;
      end
  end
  
  COM = GetCOM(Bins);
  for i=1:length(Bins)
      if isempty(Bins{i})==0
       a = GetCubeSide(OT.BinBoundaries(i,:));
       Bins{i}(:,5) = a;
      end
      
  end
  
[Fx, Fy, Fz] = Force(N, Bins, COM, OT,theta);
G = 6.673*10^(-11);
acc = G*[Fx, Fy, Fz];
  
t         = 0;     % current time of the simulation
tEnd      = 1000;    % time at which simulation ends
dt        = 0.1;  % timestep

Nt = ceil(tEnd/dt);
vel = randn(N,3);
% vel = zeros(N,3);
% t_all = (0:Nt)*dt;
pos_save = zeros(N,3,Nt+1);
pos_save(:,:,1) = OT.Points(:,1:3);
% fh = figure;

for i=1:Nt
    
    vel = vel + acc*dt/2;
    OT.Points(:,1:3) = OT.Points(:,1:3) + vel*dt;
    OT = OcTree(OT.Points(:,1:3),'binCapacity',10,'style','weighted');
           Bins=cell(OT.BinCount,1);
           OT.Points(:,4) = mass;
           OT.Points(:,5:8) = points_properties;
           
  for x=1:OT.BinCount
      k=1;
      for j=1:size(OT.Points,1)
          if OT.PointBins(j) == x
              Bins{x}(k,:) = OT.Points(j,:);
              k=k+1;
          end
      end
  end
  
  for k=1:length(Bins)      %to remove 0 rows
      if isempty(Bins{k})==0
       A = Bins{k};    
       A(A(:,1)==0,:) = [];
       Bins{k}=A;
      end
  end
    COM = GetCOM(Bins);
     for n=1:length(Bins)
      if isempty(Bins{n})==0
       a = GetCubeSide(OT.BinBoundaries(n,:));
       Bins{n}(:,5) = a;
      end  
     end
     
     
    [Fx, Fy, Fz] = Force(N, Bins, COM, OT,theta);
    acc = G*[Fx, Fy, Fz];
    vel = vel + acc*dt/2;
    t = t + dt;
    xlim(app.UIAxes,[-200 200]);
    ylim(app.UIAxes,[-200 200]);
    zlim(app.UIAxes,[-200 200]);

    
    pos_save(:,:,i+1) = OT.Points(:,1:3);
    if (plotRealTime) || (i==Nt)
%         subplot(3,1,1:2)
        xx = pos_save(:,1,max(i-100,1):i);
        yy = pos_save(:,2,max(i-100,1):i);
        zz = pos_save(:,3,max(i-100,1):i);
        plot3(app.UIAxes,xx(:),yy(:),zz(:),'.','color',[.83 .66 .88]);
%         set(gca,'color',[0 0 0])
        hold(app.UIAxes,'on')

%         plot3(app.UIAxes,OT.Points(:,1),OT.Points(:,2),OT.Points(:,3),'.','color','r','markersize',18);
        for m=1:N
            plot3(app.UIAxes,OT.Points(m,1),OT.Points(m,2),OT.Points(m,3),'.','color',[OT.Points(m,5) OT.Points(m,6) OT.Points(m,7)],'markersize',OT.Points(m,8));
        end
        hold(app.UIAxes,'off')

%         axis square
%          axis([-100 100 -100 100 -100 100])
    end
    
        drawnow
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Gravitational Interaction')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.EditField_12 = uieditfield(app.UIFigure, 'text');
            app.EditField_11 = uieditfield(app.UIFigure, 'text');
            app.EditField_10 = uieditfield(app.UIFigure, 'text');
            app.EditField_9 = uieditfield(app.UIFigure, 'text');
            app.EditField_8 = uieditfield(app.UIFigure, 'text');
            app.EditField_7 = uieditfield(app.UIFigure, 'text');
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.Image = uiimage(app.UIFigure);
            app.Image.Enable = 'off';
            app.Image.ImageSource = 'stars_space_dark1920x1080.jpg';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.TextArea_6 = uitextarea(app.UIFigure);
            app.TextArea_7 = uitextarea(app.UIFigure);
            app.TextArea_8 = uitextarea(app.UIFigure);
            app.TextArea_9 = uitextarea(app.UIFigure);
            app.TextArea_10 = uitextarea(app.UIFigure);
            app.TextArea_11 = uitextarea(app.UIFigure);
            app.TextArea_12 = uitextarea(app.UIFigure);
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'N';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'θ';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 2;
            app.EditField_13 = uieditfield(app.UIFigure, 'text');
            app.EditField_13.Value = '0.5';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end